classdef drawglycangui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VirtualGlycomeUIFigure    matlab.ui.Figure
        DrawGlycanPanel           matlab.ui.container.Panel
        DrawingPanel              matlab.ui.container.Panel
        SaveFigureButton          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        ParametersPanel           matlab.ui.container.Panel
        GOButton                  matlab.ui.control.Button
        resetbutton               matlab.ui.control.Button
        GlycopeptideOptionsPanel  matlab.ui.container.Panel
        glycanspacingtext         matlab.ui.control.Label
        aminoacidsequencetext     matlab.ui.control.Label
        glycanspace               matlab.ui.control.Slider
        aminoacid                 matlab.ui.control.Slider
        GlycanOptionsPanel        matlab.ui.container.Panel
        bondbreaksigntext         matlab.ui.control.Label
        linkagepositiontext       matlab.ui.control.Label
        linkagespacingtext        matlab.ui.control.Label
        perpendiculartext         matlab.ui.control.Label
        bondthicknesstext         matlab.ui.control.Label
        monorimthicknesstext      matlab.ui.control.Label
        perpendicular             matlab.ui.control.ListBox
        monorim                   matlab.ui.control.Slider
        bond                      matlab.ui.control.Slider
        linkspace                 matlab.ui.control.Slider
        linkposition              matlab.ui.control.Slider
        bondbreak                 matlab.ui.control.Slider
        sortbranchestext          matlab.ui.control.Label
        branchesswitch            matlab.ui.control.Switch
        centerfont                matlab.ui.control.DropDown
        centerfontweighttext      matlab.ui.control.Label
        centertext                matlab.ui.control.Spinner
        centertextsizefont        matlab.ui.control.Label
        orientation               matlab.ui.control.DropDown
        symbolsize                matlab.ui.control.DropDown
        textspinner               matlab.ui.control.Spinner
        linkagespinner            matlab.ui.control.Spinner
        glycantext                matlab.ui.control.EditField
        glycanstructuretext       matlab.ui.control.Label
        orientationtext           matlab.ui.control.Label
        symbolsizetext            matlab.ui.control.Label
        textfontsizetext          matlab.ui.control.Label
        linkagefontsizetext       matlab.ui.control.Label
        displaylinkaetext         matlab.ui.control.Label
        displaylinkageswitch      matlab.ui.control.Switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            cla(app.UIAxes)
            if strcmpi(app.symbolsize.Value,'Small')
                monosacsize = 0.25;
            elseif strcmpi(app.symbolsize.Value,'Large')
                monosacsize = 0.75;
            else
                monosacsize = 0.5;
            end
            if contains(app.glycantext.Value,'}}')
                if contains(app.glycantext.Value,'(')
                    inputformat = 'SGP2';
                else
                    inputformat = 'SGP1';
                end
            else
                inputformat = 'iupac';
            end
            if strcmpi(app.displaylinkageswitch.Value,'Yes')
                showlink = true;
            else
                showlink = false;
            end
            if strcmpi(app.perpendicular.Value,'None')
                perpendicularmono = {};
            else
                perpendicularmono = app.perpendicular.Value;
            end
            if strcmpi(app.centerfont.Value,'Normal')
                centerweight = 'normal';
            else
                centerweight = 'bold';
            end
            hold(app.UIAxes,'on');
            drawglycan(app.glycantext.Value,'orientation',app.orientation.Value,'monosacsize',monosacsize,...
                       'bondwidth',app.bond.Value,'msperiwidth',app.monorim.Value,...
                       'perpendicularmonosac',perpendicularmono,'linkinfodist',app.linkspace.Value,...
                       'linkinfotheta',app.linkposition.Value,'bondbreaksiglength',app.bondbreak.Value,...
                       'showlink',showlink,'fontsize',app.textspinner.Value,'workingmode','',...
                       'structspacing',app.glycanspace.Value,'aaspacing',app.aminoacid.Value,'fileout','',...
                       'visible','on','inputformat',inputformat,'specialoptions',{},...
                       'linkfontsize',app.linkagespinner.Value,'sortbranches',app.branchesswitch.Value,...
                       'figurehandle',app.UIAxes,'resize',true,'pointsperunit',50,'centertextsize',app.centertext.Value,...
                       'centerfontweight',centerweight,'originpointoffset',[0,0]);
            axis(app.UIAxes,'off')
            hold(app.UIAxes,'off');
        end

        % Button pushed function: resetbutton
        function resetbuttonButtonPushed(app, event)
            app.displaylinkageswitch.Value = 'Yes';
            app.linkagespinner.Value = 14;
            app.branchesswitch.Value = 'Yes';
            app.textspinner.Value = 14;
            app.symbolsize.Value = 'Medium';
            app.orientation.Value = 'Left';
            app.centertext.Value = 14;
            app.centerfont.Value = 'Medium';
            app.monorim.Value = 1;
            app.bond.Value = 2;
            app.perpendicular.Value = {'Fuc','Xyl','Deoxyhexose','Pentose'};
            app.linkspace.Value = 0.7;
            app.linkposition.Value = -30;
            app.bondbreak.Value = 0.5;
            app.aminoacid.Value = 0.5;
            app.glycanspace.Value = 1;
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            [outputfilename, pathname,~] = uiputfile({'*.jpg';'*.png';'*.eps';'*.pdf';'*.gif';...
                                                       '*.emf';'*.tif'},'Save figure to a file');
            app.VirtualGlycomeUIFigure.Visible = 'off';
            app.VirtualGlycomeUIFigure.Visible = 'on';
            if(isequal(outputfilename,0) || isequal(pathname,0))
                msgbox('No File Selected');
            else
                outfilename = fullfile(pathname,outputfilename);
                exportgraphics(app.UIAxes,outfilename,'Resolution',600); %,'ContentType','vector'
%                 web(outfilename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VirtualGlycomeUIFigure = uifigure('Visible', 'off');
            app.VirtualGlycomeUIFigure.Name = 'Virtual Glycome';
            app.DrawGlycanPanel = uipanel(app.VirtualGlycomeUIFigure);
            app.DrawGlycanPanel.Title = 'DrawGlycan';
            app.ParametersPanel = uipanel(app.DrawGlycanPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.displaylinkageswitch = uiswitch(app.ParametersPanel, 'slider');
            app.displaylinkageswitch.Items = {'No', 'Yes'};
            app.displaylinkageswitch.Value = 'Yes';
            app.displaylinkaetext = uilabel(app.ParametersPanel);
            app.displaylinkaetext.Text = '2. Display Linkage:';
            app.linkagefontsizetext = uilabel(app.ParametersPanel);
            app.linkagefontsizetext.Text = '3. Linkage Font Size:';
            app.textfontsizetext = uilabel(app.ParametersPanel);
            app.textfontsizetext.Text = '5. Text Font Size:';
            app.symbolsizetext = uilabel(app.ParametersPanel);
            app.symbolsizetext.Text = '6. Symbol Size:';
            app.orientationtext = uilabel(app.ParametersPanel);
            app.orientationtext.Text = '7. Orientation:';
            app.glycanstructuretext = uilabel(app.ParametersPanel);
            app.glycanstructuretext.Text = '1. Glycan Structure:';
            app.glycantext = uieditfield(app.ParametersPanel, 'text');
            app.linkagespinner = uispinner(app.ParametersPanel);
            app.linkagespinner.Limits = [0 Inf];
            app.linkagespinner.Value = 14;
            app.textspinner = uispinner(app.ParametersPanel);
            app.textspinner.Limits = [0 Inf];
            app.textspinner.Value = 14;
            app.symbolsize = uidropdown(app.ParametersPanel);
            app.symbolsize.Items = {'Small', 'Medium', 'Large'};
            app.symbolsize.Value = 'Medium';
            app.orientation = uidropdown(app.ParametersPanel);
            app.orientation.Items = {'Left', 'Right', 'Up', 'Down'};
            app.orientation.Value = 'Left';
            app.centertextsizefont = uilabel(app.ParametersPanel);
            app.centertextsizefont.Text = '8. Center Text Size:';
            app.centertext = uispinner(app.ParametersPanel);
            app.centertext.Limits = [0 Inf];
            app.centertext.Value = 14;
            app.centerfontweighttext = uilabel(app.ParametersPanel);
            app.centerfontweighttext.Text = '9. Center Font Weight:';
            app.centerfont = uidropdown(app.ParametersPanel);
            app.centerfont.Items = {'Normal', 'Bold'};
            app.centerfont.Value = 'Normal';
            app.branchesswitch = uiswitch(app.ParametersPanel, 'slider');
            app.branchesswitch.Items = {'No', 'Yes'};
            app.branchesswitch.Value = 'Yes';
            app.sortbranchestext = uilabel(app.ParametersPanel);
            app.sortbranchestext.Text = '4. Sort Branches:';
            app.GlycanOptionsPanel = uipanel(app.ParametersPanel);
            app.GlycanOptionsPanel.Title = 'Glycan Options';
            app.bondbreak = uislider(app.GlycanOptionsPanel);
            app.bondbreak.Limits = [0.1 1];
            app.bondbreak.Value = 0.5;
            app.linkposition = uislider(app.GlycanOptionsPanel);
            app.linkposition.Limits = [-45 45];
            app.linkposition.Value = -30;
            app.linkspace = uislider(app.GlycanOptionsPanel);
            app.linkspace.Limits = [0.5 1];
            app.linkspace.Value = 0.7;
            app.bond = uislider(app.GlycanOptionsPanel);
            app.bond.Limits = [1 6];
            app.bond.Value = 2;
            app.monorim = uislider(app.GlycanOptionsPanel);
            app.monorim.Limits = [1 10];
            app.monorim.Value = 1;
            app.perpendicular = uilistbox(app.GlycanOptionsPanel);
            app.perpendicular.Items = {'Fuc', 'Xyl', 'Pentose', 'Deoxyhexose', 'None'};
            app.perpendicular.Multiselect = 'on';
            app.perpendicular.Value = {'Fuc', 'Xyl', 'Pentose', 'Deoxyhexose'};
            app.monorimthicknesstext = uilabel(app.GlycanOptionsPanel);
            app.monorimthicknesstext.Text = {'10. Thickness of '; '       Mono. Rim:'};
            app.bondthicknesstext = uilabel(app.GlycanOptionsPanel);
            app.bondthicknesstext.Text = {'11. Thickness of '; '       Bond:'};
            app.perpendiculartext = uilabel(app.GlycanOptionsPanel);
            app.perpendiculartext.Text = {'12. Perpendicular '; '       Monosaccharides:'};
            app.linkagespacingtext = uilabel(app.GlycanOptionsPanel);
            app.linkagespacingtext.Text = {'13. Linkage Text '; '       Spacing:'};
            app.linkagepositiontext = uilabel(app.GlycanOptionsPanel);
            app.linkagepositiontext.Text = {'14. Linkage Text'; '       Position:'};
            app.bondbreaksigntext = uilabel(app.GlycanOptionsPanel);
            app.bondbreaksigntext.Text = {'15. Bond Break '; '      Signs Size:'};
            app.GlycopeptideOptionsPanel = uipanel(app.ParametersPanel);
            app.GlycopeptideOptionsPanel.Title = 'Glycopeptide Options';
            app.aminoacid = uislider(app.GlycopeptideOptionsPanel);
            app.aminoacid.Limits = [0.3 1];
            app.aminoacid.Value = 0.5;
            app.glycanspace = uislider(app.GlycopeptideOptionsPanel);
            app.glycanspace.Limits = [1 3];
            app.glycanspace.Value = 1;
            app.aminoacidsequencetext = uilabel(app.GlycopeptideOptionsPanel);
            app.aminoacidsequencetext.Text = '16. Amino Acid Spacing:';
            app.glycanspacingtext = uilabel(app.GlycopeptideOptionsPanel);
            app.glycanspacingtext.Text = {'17. Glycan Spacing '; '      (if more than 1):'};
            app.resetbutton = uibutton(app.ParametersPanel, 'push');
            app.resetbutton.ButtonPushedFcn = createCallbackFcn(app, @resetbuttonButtonPushed, true);
            app.resetbutton.Text = 'Reset to Original Values';
            app.GOButton = uibutton(app.ParametersPanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.DrawingPanel = uipanel(app.DrawGlycanPanel);
            app.DrawingPanel.Title = 'Drawing';
            app.UIAxes = uiaxes(app.DrawingPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SaveFigureButton = uibutton(app.DrawingPanel, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            % Show the figure after all components are created
            app.VirtualGlycomeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawglycangui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VirtualGlycomeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VirtualGlycomeUIFigure)
        end
    end
end