classdef glyfraggui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        glyfrag                matlab.ui.Figure
        glycanfragmentation    matlab.ui.container.Panel
        Fragmentation          matlab.ui.container.Panel
        glyfragtext            matlab.ui.control.Label
        uitable_fragdisp       matlab.ui.control.Table
        pushbutton_reset       matlab.ui.control.Button
        pushbutton_frag        matlab.ui.control.Button
        SortingButtonGroup     matlab.ui.container.ButtonGroup
        DefaultButton          matlab.ui.control.RadioButton
        mzButton               matlab.ui.control.RadioButton
        IonTypeButton          matlab.ui.control.RadioButton
        ChargeButton           matlab.ui.control.RadioButton
        SGPButton              matlab.ui.control.RadioButton
        sgpplot                matlab.ui.control.UIAxes
        ParametersButtonGroup  matlab.ui.container.ButtonGroup
        chargelessequal        matlab.ui.control.RadioButton
        chargeequal            matlab.ui.control.RadioButton
        chargelabel            matlab.ui.control.Label
        maxz                   matlab.ui.control.NumericEditField
        newsgptext             matlab.ui.control.Label
        edit_sgp               matlab.ui.control.EditField
        chargetext             matlab.ui.control.Label
        ngfrag                 matlab.ui.control.NumericEditField
        AnomericEntityLabel    matlab.ui.control.Label
        Ano                    matlab.ui.control.DropDown
        GlycanFormLabel        matlab.ui.control.Label
        Form                   matlab.ui.control.DropDown
        adductnametext         matlab.ui.control.Label
        AdductField            matlab.ui.control.EditField
        adductcounttext        matlab.ui.control.Label
        LoadmassStructButton   matlab.ui.control.Button
        edit_massedits         matlab.ui.control.EditField
        IonizationLabel        matlab.ui.control.Label
        Ion                    matlab.ui.control.DropDown
        adductcount            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        totalFragIons
        tableallfrag % All glycan fragments
        masseditsfilename
    end
    
    methods (Access = private)        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function fraggui_OpeningFcn(app, varargin)
            
        end

        % Value changed function: ngfrag
        function ngfragValueChanged(app, event)
            value = app.ngfrag.Value;
            if(isempty(value)||isnan(value)||(value<0))
                app.ngfrag.Value = 1;
                warndlg('Only nonnegative number is allowed for maximum number of fragments on glycans',...
                    'Incorrect Fragment Input');
            end
        end

        % Value changed function: maxz
        function maxzValueChanged(app, event)
            value = app.maxz.Value;
            if(isempty(value)||isnan(value)||(value<0))
                app.maxz.Value = 1;
                warndlg('Only positive number is allowed for ion charge',...
                    'Incorrect Charge Input');
            end
        end

        % Button pushed function: LoadmassStructButton
        function LoadmassStructButtonPushed(app, event)
            [filename,pathname,~] = uigetfile({'*.mat'},'Pick a file storing glycan library mass edits');
            app.glyfrag.Visible = 'off';
            app.glyfrag.Visible = 'on';
            if(isequal(filename,0) || isequal(pathname,0))
                msgbox('File NOT Selected');
                app.masseditsfilename = [];
                app.edit_massedits.Value = '';
            else
                try
                    app.masseditsfilename = fullfile(pathname,filename);
                    app.edit_massedits.Value = app.masseditsfilename;
                catch error
                    errordlg(error.message,'Please select file again');
                    app.edit_massedits.Value = '';
                    return
                end
            end
        end

        % Button pushed function: pushbutton_frag
        function pushbutton_fragButtonPushed(app, event)
            app.uitable_fragdisp.Data = [];
            app.uitable_fragdisp.ColumnName = [];
            app.DefaultButton.Value = 1;
            app.SGPButton.Value = 0;
            app.ChargeButton.Value = 0;
            app.mzButton.Value = 0;
            app.IonTypeButton.Value = 0;
            %glycan sequence check
            % it does not have any character it does not support
            if isempty(app.edit_sgp.Value)
                warndlg('Please enter a valid glycan sequence','Incorrect Sequence Input');
                return
            end
            glycancharregexpr = '[{}a-z<>\d\+\-os\.]';
            anynongpletterchar = regexp(app.edit_sgp.Value,glycancharregexpr,'once');
            if(isempty(anynongpletterchar))
                errordlg('Please enter a valid glycan sequence','Incorrect Sequence Input');
                return
            end
            if contains(app.edit_sgp.Value,'{')
                glyStruct.name = app.edit_sgp.Value;
            else
                glyStruct.name = IUPAC2Sgp(app.edit_sgp.Value);
            end
            if ~isempty(app.masseditsfilename)
                massedits =  load(app.masseditsfilename);
                massedits = massedits.massStruct;
            else
                massedits = [];
            end
            adduct.name = app.AdductField.Value;
            if contains(app.Ano.Value,' ')
                glyStruct.ano = extractBefore(app.Ano.Value,' ');
            else
                glyStruct.ano = app.Ano.Value;
            end
            glyStruct.form = app.Form.Value;
            glyStruct.ion = app.Ion.Value;
            if ~isempty(massedits)
                GlycanNew = Glycan;
                for gg = 1:size(massedits.modSym)
                    GlycanNew.glycanMSMap(massedits.modSym{gg}) = massedits.modMass(gg);
                end
                AdductNew = Adduct;
                for zz = 1:size(massedits.addName)
                    AdductNew.mass(massedits.addName{zz}) = massedits.addMass(zz);
                    AdductNew.charge(massedits.addName{zz}) = massedits.addZ(zz);
                end
                AnomericNew = Anomeric;
                for aa = 1:size(massedits.ano)
                    AnomericNew.mass(massedits.ano) = massedits.anoMass;
                end
            end
            if app.chargelessequal.Value == 1 % Can only be done with single adduct
                glyStruct.z = 1;
                adduct.count = 1;
                glyStruct.adduct = adduct;
                if ~isempty(massedits)
                    glyStruct.mz = glyMZCalc(glyStruct,GlycanNew,AnomericNew,AdductNew);
                else
                    glyStruct.mz = glyMZCalc(glyStruct,Glycan,Anomeric,Adduct);
                end
                app.totalFragIons = FragmentGlyGUI(glyStruct,app.ngfrag.Value);                    
                for i=2:app.maxz.Value
                    glyStruct.z = i;
                    adduct.count = i;
                    glyStruct.adduct = adduct;
                    if ~isempty(massedits)
                        glyStruct.mz = glyMZCalc(glyStruct,GlycanNew,AnomericNew,AdductNew);
                        AllFragIons = FragmentGlyGUI(glyStruct,app.ngfrag.Value,GlycanNew,AnomericNew,AdductNew);
                    else
                        glyStruct.mz = glyMZCalc(glyStruct);
                        AllFragIons = FragmentGlyGUI(glyStruct,app.ngfrag.Value);
                    end
                    fragsize = length(app.totalFragIons);
                    for j = 1 : length(AllFragIons)
                        app.totalFragIons(j+fragsize) = AllFragIons(j);
                    end
                end
            else
                glyStruct.z = app.maxz.Value;
                adduct.count = app.adductcount.Value;
                glyStruct.adduct = adduct;
                if ~isempty(massedits)
                    glyStruct.mz = glyMZCalc(glyStruct,GlycanNew,AnomericNew,AdductNew);
                    app.totalFragIons  = FragmentGlyGUI(glyStruct,app.ngfrag.Value,GlycanNew,AnomericNew,AdductNew);
                else
                    glyStruct.mz = glyMZCalc(glyStruct);
                    app.totalFragIons  = FragmentGlyGUI(glyStruct,app.ngfrag.Value);
                end
            end           
            tempmz = arrayfun(@(x)num2str(x,'%16.8f'),[app.totalFragIons.mz],'UniformOutput',0);
            [app.totalFragIons.mz]   = tempmz{:};
            app.totalFragIons = rmfield(app.totalFragIons,'unitindex');
            colnames = fieldnames(app.totalFragIons);
            app.totalFragIons = struct2table(app.totalFragIons);
            scoretabledata = table2cell(app.totalFragIons);
            scoretabledata(:,3) = cellfun(@(x) [x.name,',',num2str(x.count)],scoretabledata(:,3),'UniformOutput',0);
            app.uitable_fragdisp.Data = scoretabledata;
            app.uitable_fragdisp.ColumnName = colnames;
            app.tableallfrag = scoretabledata;
%             matchresult.ionmatchindex = num2cell(1:size(totalFragIons,1));
%             specialoptions = getdgfrag({0,app.ngfrag.Value,0},totalFragIons,matchresult);
            hold(app.sgpplot,'on');
            if contains(app.edit_sgp.Value,'{')
                figinfoB = drawglycan(app.edit_sgp.Value,'figurehandle',app.sgpplot,'inputformat','SGP1',...
                    'perpendicularmonosac',{'Deoxyhexose','Pentose'});%...
                    %,'specialoptions',specialoptions);
            else
                figinfoB = drawglycan(app.edit_sgp.Value,'figurehandle',app.sgpplot,'inputformat','IUPAC',...
                    'perpendicularmonosac',{'Deoxyhexose','Pentose'});%...
                    %,'specialoptions',specialoptions);
            end
            tcpB = figinfoB.tcp + [-.5 -.5;.5 .5];  % add margin to drawglycan figure
            app.sgpplot.XLim = [tcpB(1,1),tcpB(2,1)];
            app.sgpplot.YLim = [tcpB(1,2),tcpB(2,2)];
            axis(app.sgpplot,'off')
        end

        % Selection changed function: SortingButtonGroup
        function SortingButtonGroupSelectionChanged(app, event)
            selectedButton = app.SortingButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Default')
                app.uitable_fragdisp.Data = app.tableallfrag;
            elseif strcmp(selectedButton.Text,'SGP')
                [scoretabledata,~] = sortrows(app.tableallfrag,1,'ascend');
                app.uitable_fragdisp.Data = scoretabledata;
            elseif strcmp(selectedButton.Text,'m/z')
                mzvalues = cell2mat(cellfun(@(x) str2double(x),app.tableallfrag(:,7),'UniformOutput',false));
                [~,mzind] = sort(mzvalues);
                scoretabledata = app.tableallfrag(mzind,:);
                app.uitable_fragdisp.Data = scoretabledata;
            elseif strcmp(selectedButton.Text,'Ion Type')
                [scoretabledata,~] = sortrows(app.tableallfrag,6,'descend');
                app.uitable_fragdisp.Data = scoretabledata;
            elseif strcmp(selectedButton.Text,'Charge')
                zvalues = cell2mat(cellfun(@(x) str2double(x),app.tableallfrag(:,2),'UniformOutput',false));
                [~,zind] = sort(zvalues);
                scoretabledata = app.tableallfrag(zind,:);
                app.uitable_fragdisp.Data = scoretabledata;
            end
        end

        % Button pushed function: pushbutton_reset
        function pushbutton_resetButtonPushed(app, event)
            app.edit_sgp.Value = '';
            app.uitable_fragdisp.Data = [];
            app.uitable_fragdisp.ColumnName = [];
            app.DefaultButton.Value = 1;
            app.SGPButton.Value = 0;
            app.ChargeButton.Value = 0;
            app.mzButton.Value = 0;
            app.IonTypeButton.Value = 0;
            app.ngfrag.Value = 2;
            app.maxz.Value = 4;
            app.chargelessequal.Value = 1;
            app.chargeequal.Value = 0;
            app.Ano.Value = 'free';
            app.Form.Value = 'OH';
            app.AdductField.Value = 'H';
            app.adductcount.Value = 4;
            cla(app.sgpplot)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.glyfrag = uifigure('Visible', 'off');
            app.glyfrag.Name = 'Glycomics Analysis Toolbox';
            app.glyfrag.Tag = 'abc';
            app.glycanfragmentation = uipanel(app.glyfrag);
            app.glycanfragmentation.Title = 'Glycan Fragmentation';
            app.glycanfragmentation.Tag = 'uipanel4';
            app.Fragmentation = uipanel(app.glycanfragmentation);
            app.Fragmentation.Title = 'Results';
            app.Fragmentation.Tag = 'uipanel7';
            app.glyfragtext = uilabel(app.Fragmentation);
            app.glyfragtext.Tag = 'text17';
            app.glyfragtext.Text = 'Glycan Fragments:';
            app.uitable_fragdisp = uitable(app.Fragmentation);
            app.uitable_fragdisp.ColumnEditable = [false false];
            app.uitable_fragdisp.Tag = 'uitable_fragdisp';
            app.pushbutton_reset = uibutton(app.Fragmentation, 'push');
            app.pushbutton_reset.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_resetButtonPushed, true);
            app.pushbutton_reset.Tag = 'pushbutton_reset';
            app.pushbutton_reset.Text = 'Clear Fragments';
            app.pushbutton_frag = uibutton(app.Fragmentation, 'push');
            app.pushbutton_frag.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_fragButtonPushed, true);
            app.pushbutton_frag.Tag = 'pushbutton_frag';
            app.pushbutton_frag.Text = {'Perform'; 'Fragmentation'};
            app.SortingButtonGroup = uibuttongroup(app.Fragmentation);
            app.SortingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SortingButtonGroupSelectionChanged, true);
            app.SortingButtonGroup.Title = 'Sorting:';
            app.DefaultButton = uiradiobutton(app.SortingButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.mzButton = uiradiobutton(app.SortingButtonGroup);
            app.mzButton.Text = 'm/z';
            app.IonTypeButton = uiradiobutton(app.SortingButtonGroup);
            app.IonTypeButton.Text = 'Ion Type';
            app.ChargeButton = uiradiobutton(app.SortingButtonGroup);
            app.ChargeButton.Text = 'Charge';
            app.SGPButton = uiradiobutton(app.SortingButtonGroup);
            app.SGPButton.Text = 'SGP';
            app.sgpplot = uiaxes(app.Fragmentation);
            app.sgpplot.XTick = [];
            app.sgpplot.YTick = [];
            app.ParametersButtonGroup = uibuttongroup(app.glycanfragmentation);
            app.ParametersButtonGroup.Title = 'Parameters';
            app.chargelessequal = uiradiobutton(app.ParametersButtonGroup);
            app.chargelessequal.Text = 'Charge <= Z';
            app.chargelessequal.Value = true;
            app.chargeequal = uiradiobutton(app.ParametersButtonGroup);
            app.chargeequal.Text = 'Charge = Z';
            app.chargelabel = uilabel(app.ParametersButtonGroup);
            app.chargelabel.Text = '3. Charge (Z):';
            app.maxz = uieditfield(app.ParametersButtonGroup, 'numeric');
            app.maxz.ValueChangedFcn = createCallbackFcn(app, @maxzValueChanged, true);
            app.maxz.Value = 4;
            app.newsgptext = uilabel(app.ParametersButtonGroup);
            app.newsgptext.Text = '1. Glycan Structure:';
            app.edit_sgp = uieditfield(app.ParametersButtonGroup, 'text');
            app.chargetext = uilabel(app.ParametersButtonGroup);
            app.chargetext.Text = {'2. Max Glycan'; 'Fragmentation:'};
            app.ngfrag = uieditfield(app.ParametersButtonGroup, 'numeric');
            app.ngfrag.ValueChangedFcn = createCallbackFcn(app, @ngfragValueChanged, true);
            app.ngfrag.Value = 2;
            app.AnomericEntityLabel = uilabel(app.ParametersButtonGroup);
            app.AnomericEntityLabel.Text = '5. Anomeric Entity:';
            app.Ano = uidropdown(app.ParametersButtonGroup);
            app.Ano.Items = {'free', 'Me (Methylated)', 'Ac (Acetylated)', 'Bn (Benzyl gp)', 'SBn (S-Benzyl gp)', 'ONAP', 'SNAP', 'AA (2-aminobenzoic acid)', 'AB (2-aminobenzamide)', 'ABperM (permethylated AB)', 'N2AB (2-amino-N', ' N-dimethylbenzamide)', 'N2ABperM (permethylated N2AB)', 'P (Procainamide)', 'PperM (permethylated Procainamide)', 'pNP (para-Nitrophenol)', 'Allyl'};
            app.Ano.Value = 'free';
            app.GlycanFormLabel = uilabel(app.ParametersButtonGroup);
            app.GlycanFormLabel.Text = '6. Glycan Form:';
            app.Form = uidropdown(app.ParametersButtonGroup);
            app.Form.Items = {'OH', 'Me', 'Ac'};
            app.Form.Value = 'OH';
            app.adductnametext = uilabel(app.ParametersButtonGroup);
            app.adductnametext.Text = '7. Adduct(s):';
            app.AdductField = uieditfield(app.ParametersButtonGroup, 'text');
            app.AdductField.Value = 'H';
            app.adductcounttext = uilabel(app.ParametersButtonGroup);
            app.adductcounttext.Text = '8. Adduct Count:';
            app.LoadmassStructButton = uibutton(app.ParametersButtonGroup, 'push');
            app.LoadmassStructButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmassStructButtonPushed, true);
            app.LoadmassStructButton.Text = 'Load massStruct';
            app.edit_massedits = uieditfield(app.ParametersButtonGroup, 'text');
            app.IonizationLabel = uilabel(app.ParametersButtonGroup);
            app.IonizationLabel.Text = '4. Ionization:';
            app.Ion = uidropdown(app.ParametersButtonGroup);
            app.Ion.Items = {'Positive', 'Negative'};
            app.Ion.Value = 'Positive';
            app.adductcount = uieditfield(app.ParametersButtonGroup, 'text');
            app.adductcount.Value = '4';
            % Show the figure after all components are created
            app.glyfrag.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = glyfraggui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.glyfrag)

                % Execute the startup function
                runStartupFcn(app, @(app)fraggui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.glyfrag)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.glyfrag)
        end
    end
end